classdef dialog_box_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        txtImageDirectory            matlab.ui.control.Label
        txtOutputDirectory           matlab.ui.control.Label
        Cy3Threshold                 matlab.ui.control.EditField
        FITCthreshold                matlab.ui.control.EditField
        imageDirectory               matlab.ui.control.Button
        outputDirectory              matlab.ui.control.Button
        run                          matlab.ui.control.Button
        text5                        matlab.ui.control.Label
        text6                        matlab.ui.control.Label
        text7                        matlab.ui.control.Label
        text8                        matlab.ui.control.Label
        ThresholdImageDropDownLabel  matlab.ui.control.Label
        ThresholdImageDropDown       matlab.ui.control.DropDown
        ListBoxLabel                 matlab.ui.control.Label
        ListBox                      matlab.ui.control.ListBox
        listBoxLayers                matlab.ui.control.Label
        ListBox_2Label               matlab.ui.control.Label
        ListBox_2                    matlab.ui.control.ListBox
        ListBox_3Label               matlab.ui.control.Label
        ListBox_3                    matlab.ui.control.ListBox
        ListBox_4Label               matlab.ui.control.Label
        ListBox_4                    matlab.ui.control.ListBox
        ListBox_5Label               matlab.ui.control.Label
        ListBox_5                    matlab.ui.control.ListBox
        ListBox_6Label               matlab.ui.control.Label
        ListBox_6                    matlab.ui.control.ListBox
        ListBox_7Label               matlab.ui.control.Label
        ListBox_7                    matlab.ui.control.ListBox
        ListBox_8Label               matlab.ui.control.Label
        ListBox_8                    matlab.ui.control.ListBox
        listBoxLayers_2              matlab.ui.control.Label
        listBoxLayers_3              matlab.ui.control.Label
        listBoxLayers_4              matlab.ui.control.Label
        listBoxLayers_5              matlab.ui.control.Label
        listBoxLayers_6              matlab.ui.control.Label
        listBoxLayers_7              matlab.ui.control.Label
        listBoxLayers_8              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function dialog_box_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to dialog_box (see VARARGIN)
            
            % Choose default command line output for dialog_box
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            app.ListBoxLabel.Visible = 'off';
            app.ListBox.Visible = 'off';
            app.listBoxLayers.Visible = 'off';

            app.ListBox_2Label.Visible = 'off';
            app.ListBox_2.Visible = 'off';
            app.listBoxLayers_2.Visible = 'off';

            app.ListBox_3Label.Visible = 'off';
            app.ListBox_3.Visible = 'off';
            app.listBoxLayers_3.Visible = 'off';

            app.ListBox_4Label.Visible = 'off';
            app.ListBox_4.Visible = 'off';
            app.listBoxLayers_4.Visible = 'off';

            app.ListBox_5Label.Visible = 'off';
            app.ListBox_5.Visible = 'off';
            app.listBoxLayers_5.Visible = 'off';

            app.ListBox_6Label.Visible = 'off';
            app.ListBox_6.Visible = 'off';
            app.listBoxLayers_6.Visible = 'off';

            app.ListBox_7Label.Visible = 'off';
            app.ListBox_7.Visible = 'off';
            app.listBoxLayers_7.Visible = 'off';

            app.ListBox_8Label.Visible = 'off';
            app.ListBox_8.Visible = 'off';
            app.listBoxLayers_8.Visible = 'off';
            
            app.ThresholdImageDropDownLabel.Visible = 'off';
            app.ThresholdImageDropDown.Visible = 'off';
        end

        % Button pushed function: imageDirectory
        function imageDirectory_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to imageDirectory (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            imgdir = uigetdir();
            set(handles.txtImageDirectory, 'string', imgdir);
            currentDir = pwd;
            cd(imgdir);

            images = extractfield(dir('**/*COMBO.tif'), 'name');

            app.ThresholdImageDropDown.Items = {};
            
            listBoxLabels = {
                app.ListBoxLabel; app.ListBox_2Label;
                app.ListBox_3Label; app.ListBox_4Label;
                app.ListBox_5Label; app.ListBox_6Label;
                app.ListBox_7Label; app.ListBox_8Label;
                };
            listBoxes = {
                app.ListBox; app.ListBox_2;
                app.ListBox_3; app.ListBox_4;
                app.ListBox_5; app.ListBox_6;
                app.ListBox_7; app.ListBox_8;
                };
            listBoxExclusionLayers = {
                app.listBoxLayers; app.listBoxLayers_2;
                app.listBoxLayers_3; app.listBoxLayers_4;
                app.listBoxLayers_5; app.listBoxLayers_6;
                app.listBoxLayers_7; app.listBoxLayers_8;
                };
            
            for i = 1:8
                if i <= length(images)
                    listBoxLabels{i}.Text = images{i};
                    listBoxLabels{i}.Visible = 'on';

                    listBoxes{i}.Value = {'0'};
                    listBoxes{i}.Visible = 'on';

                    listBoxExclusionLayers{i}.Visible = 'on';
                    listBoxExclusionLayers{i}.Text = listBoxes{i}.Items{str2double(listBoxes{i}.Value) + 1};

                    app.ThresholdImageDropDown.Items = [app.ThresholdImageDropDown.Items, images{i}];
                    app.ThresholdImageDropDown.Visible = 'on';
                    app.ThresholdImageDropDownLabel.Visible = 'on';
                else
                    listBoxLabels{i}.Text = '';
                    listBoxLabels{i}.Visible = 'off';

                    listBoxes{i}.Value = {'0'};
                    listBoxes{i}.Visible = 'off';

                    listBoxExclusionLayers{i}.Visible = 'off';
                    listBoxExclusionLayers{i}.Text = '';
                end
            end
            cd(currentDir);
        end

        % Button pushed function: outputDirectory
        function outputDirectory_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to outputDirectory (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            outdir = uigetdir();
            set(handles.txtOutputDirectory, 'string', outdir);
        end

        % Button pushed function: run
        function run_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to run (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            imagefolder = get(handles.txtImageDirectory, 'string');
            outfolder = get(handles.txtOutputDirectory, 'string');
            Cy3threshold = str2double(get(handles.Cy3Threshold, 'string'));
            FITCthreshold = str2double(get(handles.FITCthreshold, 'string'));
            if strcmp(imagefolder, 'Select Image Directory') || strcmp(outfolder, 'Select Data Output Directory') || isnan(Cy3threshold) || isnan(FITCthreshold)
                errordlg('All fields are required');
            else
                exclusion_data = {
                    app.ListBoxLabel.Text, char(app.ListBox.Value);
                    app.ListBox_2Label.Text, char(app.ListBox_2.Value);
                    app.ListBox_3Label.Text, char(app.ListBox_3.Value);
                    app.ListBox_4Label.Text, char(app.ListBox_4.Value);
                    app.ListBox_5Label.Text, char(app.ListBox_5.Value);
                    app.ListBox_6Label.Text, char(app.ListBox_6.Value);
                    app.ListBox_7Label.Text, char(app.ListBox_7.Value);
                    app.ListBox_8Label.Text, char(app.ListBox_8.Value);
                    };
                cross_section_staining_analysis(imagefolder, Cy3threshold, FITCthreshold, outfolder, exclusion_data, app.ThresholdImageDropDown.Value);
            end
        end

        % Value changed function: ListBox
        function listbox_Callback(app, event)
            app.listBoxLayers.Text = '';
            for i = 1:length(app.ListBox.Value)
                if mod(i, 2)
                    if strcmp(app.listBoxLayers.Text, '')
                        app.listBoxLayers.Text = sprintf('%s', app.ListBox.Items{str2double(app.ListBox.Value{i}) + 1});
                        continue
                    end
                    app.listBoxLayers.Text = sprintf('%s,\n%s', app.listBoxLayers.Text, app.ListBox.Items{str2double(app.ListBox.Value{i}) + 1});
                else
                    app.listBoxLayers.Text = sprintf('%s, %s', app.listBoxLayers.Text, app.ListBox.Items{str2double(app.ListBox.Value{i}) + 1});
                end
            end
        end

        % Value changed function: ListBox_2
        function listbox2_Callback(app, event)
            app.listBoxLayers_2.Text = '';
            for i = 1:length(app.ListBox_2.Value)
                if mod(i, 2)
                    if strcmp(app.listBoxLayers_2.Text, '')
                        app.listBoxLayers_2.Text = sprintf('%s', app.ListBox_2.Items{str2double(app.ListBox_2.Value{i}) + 1});
                        continue
                    end
                    app.listBoxLayers_2.Text = sprintf('%s,\n%s', app.listBoxLayers_2.Text, app.ListBox_2.Items{str2double(app.ListBox_2.Value{i}) + 1});
                else
                    app.listBoxLayers_2.Text = sprintf('%s, %s', app.listBoxLayers_2.Text, app.ListBox_2.Items{str2double(app.ListBox_2.Value{i}) + 1});
                end
            end
        end

        % Value changed function: ListBox_3
        function listbox3_Callback(app, event)
            app.listBoxLayers_3.Text = '';
            for i = 1:length(app.ListBox_3.Value)
                if mod(i, 2)
                    if strcmp(app.listBoxLayers_3.Text, '')
                        app.listBoxLayers_3.Text = sprintf('%s', app.ListBox_3.Items{str2double(app.ListBox_3.Value{i}) + 1});
                        continue
                    end
                    app.listBoxLayers_3.Text = sprintf('%s,\n%s', app.listBoxLayers_3.Text, app.ListBox_3.Items{str2double(app.ListBox_3.Value{i}) + 1});
                else
                    app.listBoxLayers_3.Text = sprintf('%s, %s', app.listBoxLayers_3.Text, app.ListBox_3.Items{str2double(app.ListBox_3.Value{i}) + 1});
                end
            end
        end

        % Value changed function: ListBox_4
        function listbox4_Callback(app, event)
            app.listBoxLayers_4.Text = '';
            for i = 1:length(app.ListBox_4.Value)
                if mod(i, 2)
                    if strcmp(app.listBoxLayers_4.Text, '')
                        app.listBoxLayers_4.Text = sprintf('%s', app.ListBox_4.Items{str2double(app.ListBox_4.Value{i}) + 1});
                        continue
                    end
                    app.listBoxLayers_4.Text = sprintf('%s,\n%s', app.listBoxLayers_4.Text, app.ListBox_4.Items{str2double(app.ListBox_4.Value{i}) + 1});
                else
                    app.listBoxLayers_4.Text = sprintf('%s, %s', app.listBoxLayers_4.Text, app.ListBox_4.Items{str2double(app.ListBox_4.Value{i}) + 1});
                end
            end
        end

        % Value changed function: ListBox_5
        function listbox5_Callback(app, event)
            app.listBoxLayers_5.Text = '';
            for i = 1:length(app.ListBox_5.Value)
                if mod(i, 2)
                    if strcmp(app.listBoxLayers_5.Text, '')
                        app.listBoxLayers_5.Text = sprintf('%s', app.ListBox_5.Items{str2double(app.ListBox_5.Value{i}) + 1});
                        continue
                    end
                    app.listBoxLayers_5.Text = sprintf('%s,\n%s', app.listBoxLayers_5.Text, app.ListBox_5.Items{str2double(app.ListBox_5.Value{i}) + 1});
                else
                    app.listBoxLayers_5.Text = sprintf('%s, %s', app.listBoxLayers_5.Text, app.ListBox_5.Items{str2double(app.ListBox_5.Value{i}) + 1});
                end
            end
        end

        % Value changed function: ListBox_6
        function listbox6_Callback(app, event)
            app.listBoxLayers_6.Text = '';
            for i = 1:length(app.ListBox_6.Value)
                if mod(i, 2)
                    if strcmp(app.listBoxLayers_6.Text, '')
                        app.listBoxLayers_6.Text = sprintf('%s', app.ListBox_6.Items{str2double(app.ListBox_6.Value{i}) + 1});
                        continue
                    end
                    app.listBoxLayers_6.Text = sprintf('%s,\n%s', app.listBoxLayers_6.Text, app.ListBox_6.Items{str2double(app.ListBox_6.Value{i}) + 1});
                else
                    app.listBoxLayers_6.Text = sprintf('%s, %s', app.listBoxLayers_6.Text, app.ListBox_6.Items{str2double(app.ListBox_6.Value{i}) + 1});
                end
            end
        end

        % Value changed function: ListBox_7
        function listbox7_Callback(app, event)
            app.listBoxLayers_7.Text = '';
            for i = 1:length(app.ListBox_7.Value)
                if mod(i, 2)
                    if strcmp(app.listBoxLayers_7.Text, '')
                        app.listBoxLayers_7.Text = sprintf('%s', app.ListBox_7.Items{str2double(app.ListBox_7.Value{i}) + 1});
                        continue
                    end
                    app.listBoxLayers_7.Text = sprintf('%s,\n%s', app.listBoxLayers_7.Text, app.ListBox_7.Items{str2double(app.ListBox_7.Value{i}) + 1});
                else
                    app.listBoxLayers_7.Text = sprintf('%s, %s', app.listBoxLayers_7.Text, app.ListBox_7.Items{str2double(app.ListBox_7.Value{i}) + 1});
                end
            end
        end

        % Value changed function: ListBox_8
        function listbox8_Callback(app, event)
            app.listBoxLayers_8.Text = '';
            for i = 1:length(app.ListBox_8.Value)
                if mod(i, 2)
                    if strcmp(app.listBoxLayers_8.Text, '')
                        app.listBoxLayers_8.Text = sprintf('%s', app.ListBox_8.Items{str2double(app.ListBox_8.Value{i}) + 1});
                        continue
                    end
                    app.listBoxLayers_8.Text = sprintf('%s,\n%s', app.listBoxLayers_8.Text, app.ListBox_8.Items{str2double(app.ListBox_8.Value{i}) + 1});
                else
                    app.listBoxLayers_8.Text = sprintf('%s, %s', app.listBoxLayers_8.Text, app.ListBox_8.Items{str2double(app.ListBox_8.Value{i}) + 1});
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Retinal Image Analyzer';
            app.figure1.Tag = 'figure1';
            app.txtImageDirectory = uilabel(app.figure1);
            app.txtImageDirectory.Tag = 'txtImageDirectory';
            app.txtImageDirectory.Text = 'Select Image Directory';
            app.txtOutputDirectory = uilabel(app.figure1);
            app.txtOutputDirectory.Tag = 'txtOutputDirectory';
            app.txtOutputDirectory.Text = 'Select Data Output Directory';
            app.Cy3Threshold = uieditfield(app.figure1, 'text');
            app.Cy3Threshold.Tag = 'Cy3Threshold';
            app.FITCthreshold = uieditfield(app.figure1, 'text');
            app.FITCthreshold.Tag = 'FITCthreshold';
            app.imageDirectory = uibutton(app.figure1, 'push');
            app.imageDirectory.ButtonPushedFcn = createCallbackFcn(app, @imageDirectory_Callback, true);
            app.imageDirectory.Tag = 'imageDirectory';
            app.imageDirectory.Text = 'Browse';
            app.outputDirectory = uibutton(app.figure1, 'push');
            app.outputDirectory.ButtonPushedFcn = createCallbackFcn(app, @outputDirectory_Callback, true);
            app.outputDirectory.Tag = 'outputDirectory';
            app.outputDirectory.Text = 'Browse';
            app.run = uibutton(app.figure1, 'push');
            app.run.ButtonPushedFcn = createCallbackFcn(app, @run_Callback, true);
            app.run.Tag = 'run';
            app.run.Text = 'RUN';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Red Threshold';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Green Threshold';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Data Output Directory';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Image Directory';
            app.ThresholdImageDropDownLabel = uilabel(app.figure1);
            app.ThresholdImageDropDownLabel.Text = 'Threshold Image';
            app.ThresholdImageDropDown = uidropdown(app.figure1);
            app.ThresholdImageDropDown.Items = {};
            app.ThresholdImageDropDown.Value = {};
            app.ListBoxLabel = uilabel(app.figure1);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.figure1);
            app.ListBox.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer'};
            app.ListBox.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @listbox_Callback, true);
            app.ListBox.Value = {'0'};
            app.listBoxLayers = uilabel(app.figure1);
            app.listBoxLayers.Text = '';
            app.ListBox_2Label = uilabel(app.figure1);
            app.ListBox_2Label.Text = 'List Box';
            app.ListBox_2 = uilistbox(app.figure1);
            app.ListBox_2.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer'};
            app.ListBox_2.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.ListBox_2.Multiselect = 'on';
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @listbox2_Callback, true);
            app.ListBox_2.Value = {'0'};
            app.ListBox_3Label = uilabel(app.figure1);
            app.ListBox_3Label.Text = 'List Box';
            app.ListBox_3 = uilistbox(app.figure1);
            app.ListBox_3.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer'};
            app.ListBox_3.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.ListBox_3.Multiselect = 'on';
            app.ListBox_3.ValueChangedFcn = createCallbackFcn(app, @listbox3_Callback, true);
            app.ListBox_3.Value = {'0'};
            app.ListBox_4Label = uilabel(app.figure1);
            app.ListBox_4Label.Text = 'List Box';
            app.ListBox_4 = uilistbox(app.figure1);
            app.ListBox_4.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer'};
            app.ListBox_4.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.ListBox_4.Multiselect = 'on';
            app.ListBox_4.ValueChangedFcn = createCallbackFcn(app, @listbox4_Callback, true);
            app.ListBox_4.Value = {'0'};
            app.ListBox_5Label = uilabel(app.figure1);
            app.ListBox_5Label.Text = 'List Box';
            app.ListBox_5 = uilistbox(app.figure1);
            app.ListBox_5.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer'};
            app.ListBox_5.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.ListBox_5.Multiselect = 'on';
            app.ListBox_5.ValueChangedFcn = createCallbackFcn(app, @listbox5_Callback, true);
            app.ListBox_5.Value = {'0'};
            app.ListBox_6Label = uilabel(app.figure1);
            app.ListBox_6Label.Text = 'List Box';
            app.ListBox_6 = uilistbox(app.figure1);
            app.ListBox_6.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer'};
            app.ListBox_6.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.ListBox_6.Multiselect = 'on';
            app.ListBox_6.ValueChangedFcn = createCallbackFcn(app, @listbox6_Callback, true);
            app.ListBox_6.Value = {'0'};
            app.ListBox_7Label = uilabel(app.figure1);
            app.ListBox_7Label.Text = 'List Box';
            app.ListBox_7 = uilistbox(app.figure1);
            app.ListBox_7.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer'};
            app.ListBox_7.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.ListBox_7.Multiselect = 'on';
            app.ListBox_7.ValueChangedFcn = createCallbackFcn(app, @listbox7_Callback, true);
            app.ListBox_7.Value = {'0'};
            app.ListBox_8Label = uilabel(app.figure1);
            app.ListBox_8Label.Text = 'List Box';
            app.ListBox_8 = uilistbox(app.figure1);
            app.ListBox_8.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer'};
            app.ListBox_8.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.ListBox_8.Multiselect = 'on';
            app.ListBox_8.ValueChangedFcn = createCallbackFcn(app, @listbox8_Callback, true);
            app.ListBox_8.Value = {'0'};
            app.listBoxLayers_2 = uilabel(app.figure1);
            app.listBoxLayers_2.Text = '';
            app.listBoxLayers_3 = uilabel(app.figure1);
            app.listBoxLayers_3.Text = '';
            app.listBoxLayers_4 = uilabel(app.figure1);
            app.listBoxLayers_4.Text = '';
            app.listBoxLayers_5 = uilabel(app.figure1);
            app.listBoxLayers_5.Text = '';
            app.listBoxLayers_6 = uilabel(app.figure1);
            app.listBoxLayers_6.Text = '';
            app.listBoxLayers_7 = uilabel(app.figure1);
            app.listBoxLayers_7.Text = '';
            app.listBoxLayers_8 = uilabel(app.figure1);
            app.listBoxLayers_8.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialog_box_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)dialog_box_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end